classdef LasSee < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Layout                     matlab.ui.Figure
        AppGrid                    matlab.ui.container.GridLayout
        BtnGrid                    matlab.ui.container.GridLayout
        ExportButton               matlab.ui.control.Button
        CancelBtn                  matlab.ui.control.Button
        AddtoWSBtn                 matlab.ui.control.Button
        CalculateStatisticsButton  matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        LogsTab                    matlab.ui.container.Tab
        LogsTbl                    matlab.ui.control.Table
        DataTab                    matlab.ui.container.Tab
        DataTabGrid                matlab.ui.container.GridLayout
        DataTbl                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        HeaderTab                  matlab.ui.container.Tab
        HeaderTxt                  matlab.ui.control.TextArea
        FileGrid                   matlab.ui.container.GridLayout
        FilesTbl                   matlab.ui.control.Table
        FileBtnGrid                matlab.ui.container.GridLayout
        ClearButton                matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        AddBtn                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        filesdb
        exportdb
        datatable = table()
    end

    methods (Access = private)
        
        function exportdb = makeDatabase(app)
        files = fieldnames(app.filesdb);
        exportdb = struct;
        for idxfile = 1:length(files)
            currfile = app.filesdb.(files{idxfile});
            currdepth = table2array(app.filesdb.(files{idxfile}).logs(:,1));
            idxdepth = currdepth>currfile.startdepth & currdepth<currfile.enddepth;
            wellname = currfile.wellname;
            exportflag = cell2mat(currfile.logstats(:,1));
            lognames = currfile.logs.Properties.VariableNames(:,exportflag);
            if isfield(exportdb, wellname) %If this well already exists in welldb
                refdepth = table2array(exportdb.(wellname).logs(:,1));
            else %If this well does not exist in welldb
                refdepth = currdepth(idxdepth);
                exportdb.(wellname).logs = array2table(refdepth,'VariableNames',{'Depth'});
            end
            Nexplogs = length(lognames);
            for idxlog = 2:Nexplogs
                currlog = currfile.logs.(lognames{idxlog});
                log_regrid = interp1(currdepth(idxdepth),currlog(idxdepth), refdepth,'linear',NaN);
                if any(strcmp(lognames{idxlog},exportdb.(wellname).logs.Properties.VariableNames))
                    exportdb.(wellname).logs.([lognames{idxlog},'_',num2str(idxfile)]) = log_regrid;
                else
                    exportdb.(wellname).logs.(lognames{idxlog}) = log_regrid;
                end
            end
        end
        end %function
        
        function dbname = selectedfiledbname(app, address)
            files = fieldnames(app.filesdb);
            for k=1:numel(files)
                if strcmp(address,app.filesdb.(files{k}).address)
                    dbname = files{k};
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.AddtoWSBtn,'visible',~isdeployed)
        end

        % Button pushed function: AddBtn
        function AddBtnPushed(app, event)
            [filenames , path] = uigetfile('*.LAS','Select LAS files','MultiSelect','on');
            address = cellstr(strcat(path,filenames));

            
            if ~isequal(size(address{1}),[0,0])
                filenames = cellstr(filenames);
                filenames = matlab.lang.makeValidName(filenames,...
                         'ReplacementStyle','underscore','Prefix','well_');

                for k = 1:length(address)
                    existingfiles = app.FilesTbl.Data;
                    if ~any(strcmp(existingfiles,address{k}))
                        [logmat,logmnem,logdesc,laswell,~,~,nullval,...
                                depthunits,kb,~,~,lasheader]=readlas(address{k});
                        
                        logmat(logmat==nullval) = NaN;
                        idxdata = any(logmat(:,2:end),2);
                        logmat = logmat(idxdata,:);
                        logs = array2table(logmat,'VariableNames',cellstr(logmnem));
                        
                        matlabwell = matlab.lang.makeValidName(filenames{k},...
                         'ReplacementStyle','underscore','Prefix','well_');
                        dbname = matlab.lang.makeValidName(filenames{k},...
                         'ReplacementStyle','underscore','Prefix','well_');

                        app.FilesTbl.Data = [existingfiles;...
                                             {address{k},...
                                              laswell,...
                                              matlabwell,...
                                              logmat(1,1),...
                                              logmat(end,1),...
                                              kb, ...
                                              size(logmat,2)-1}];
                           
                        logstats = [num2cell(true(size(logmat,2),1)),...
                                      cellstr(logmnem),...
                                      cellstr(logdesc),...
                                      cell(size(cellstr(logmnem))),...
                                      cell(size(cellstr(logmnem))),...
                                      cell(size(cellstr(logmnem)))];                   

                        app.filesdb.(dbname).lasheader = lasheader;
                        app.filesdb.(dbname).logmnem = logmnem;
                        app.filesdb.(dbname).logdesc = logdesc;
                        app.filesdb.(dbname).logs = logs;
                        app.filesdb.(dbname).logstats = logstats;
                        app.filesdb.(dbname).wellname = matlabwell;
                        app.filesdb.(dbname).kb = kb;
                        app.filesdb.(dbname).depthunits = depthunits;
                        app.filesdb.(dbname).address = address{k};
                        app.filesdb.(dbname).startdepth = logmat(1,1);
                        app.filesdb.(dbname).enddepth = logmat(end,1);
                        app.filesdb.(dbname).dbname = dbname;
%                         app.welldb.(dbname).depth = logmat(:,1);
                        
                       app.FilesTbl.Selection = 1;
                       FilesTblSelectionChanged(app, event)
                    end
                end
            end

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            row = app.FilesTbl.Selection;
            address = app.FilesTbl.Data{row,1};
            dbname = selectedfiledbname(app, address);
            app.filesdb = rmfield(app.filesdb, dbname);
            tbldata = app.FilesTbl.Data;
            tbldata(row,:)=[];
            app.FilesTbl.Data = tbldata;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.filesdb = [];
            app.FilesTbl.Data = [];
            app.LogsTbl.Data = [];
            app.DataTbl.Data = [];
            app.HeaderTxt.Value = '';
        end

        % Callback function: FilesTbl
        function FilesTblSelectionChanged(app, event)
            try 
                address = app.FilesTbl.Data{app.FilesTbl.Selection,1};
                dbname = selectedfiledbname(app, address);
                app.HeaderTxt.Value = string(app.filesdb.(dbname).lasheader);
                app.DataTbl.Data = table2array(app.filesdb.(dbname).logs);
                app.DataTbl.ColumnName = app.filesdb.(dbname).logs.Properties.VariableNames;
                app.LogsTbl.Data = app.filesdb.(dbname).logstats;
            catch
            end

        end

        % Callback function: DataTbl
        function DataTblSelectionChanged(app, event)
            col = app.DataTbl.Selection;
            plot(app.UIAxes,app.DataTbl.Data(:,col),app.DataTbl.Data(:,1),'k','LineWidth',1.5)
            set(app.UIAxes,'YDir','reverse');
            zoom(app.UIAxes,'yon')
        end

        % Cell edit callback: LogsTbl
        function LogsTblCellEdit(app, event)
            logindex = event.Indices(:,1);
            state = event.NewData;
            address = app.FilesTbl.Data{app.FilesTbl.Selection,1};
            dbname = selectedfiledbname(app, address);
            app.filesdb.(dbname).logstats{logindex,1} = state;

        end

        % Button pushed function: CancelBtn
        function CancelBtnPushed(app, event)
            app.delete
        end

        % Button pushed function: AddtoWSBtn
        function AddtoWSBtnButtonPushed(app, event)
            exportdb = makeDatabase(app);
            assignin("base",'welldb',exportdb);
        end

        % Cell edit callback: FilesTbl
        function FilesTblCellEdit(app, event)

            indices = event.Indices;
            row = indices(1);
            col = indices(2);
            oldValue = event.PreviousData;
            newValue = event.NewData;
            address = app.FilesTbl.Data{row,1};
            dbname = selectedfiledbname(app, address);
            switch col
                case 3
                    if ~isvarname(newValue)
                        app.FilesTbl.Data{row,col}=oldValue;
                    else
                        app.filesdb.(dbname).wellname = newValue;
                    end
                case {4,5}
                    if isnan(newValue)
                        app.FilesTbl.Data{row,col}=oldValue;
                    elseif col == 4
                            app.filesdb.(dbname).startdepth = newValue;
                    elseif col == 5
                            app.filesdb.(dbname).enddepth = newValue;
                    end
            end
        end

        % Button pushed function: CalculateStatisticsButton
        function CalculateStatisticsButtonPushed(app, event)
            for row = 1:size(app.FilesTbl.Data,1)
                address = app.FilesTbl.Data{row,1};
                dbname = selectedfiledbname(app, address);
                logmat = table2array(app.filesdb.(dbname).logs);
                app.filesdb.(dbname).logstats = ...
                                [num2cell(true(size(logmat,2),1)),...
                                 cellstr(app.filesdb.(dbname).logmnem),...
                                 cellstr(app.filesdb.(dbname).logdesc),...
                                 num2cell(mean(logmat,'omitnan')'),...
                                 num2cell(min(logmat)'),...
                                 num2cell(max(logmat)')]; 
            end
            address = app.FilesTbl.Data{app.FilesTbl.Selection,1};
            dbname = selectedfiledbname(app, address);            
            app.LogsTbl.Data = app.filesdb.(dbname).logstats;

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportdb = makeDatabase(app);
            [file, path] = uiputfile({  '*.mat', 'MAT-file (*.mat)'; ...
                                        '*.xls;*xlsx', 'Excel File (*.xlsx)'; ...
                                        '*.csv', 'Comma-Separated File (*.csv)'; ...
                                        '*.txt', 'Text file (*.txt)'; ...
                                        '*.dat', 'Data file (*.dat)'}, 'Save to');
            fullname = strcat(path,file);
            [~,~,ext] = fileparts(fullname);
%             try 
                switch ext
                    case '.mat'
                        save(fullname,'exportdb','-mat')
                    case {'.xlsx'}
                        wells = fieldnames(exportdb);
                        for k = 1:length(wells)
                            writetable(exportdb.(wells{k}).logs,file,'sheet',wells{k})
                        end    
                    case {'.csv', '.dat','.txt'}
                        mkdir(path,file);
%                         rmdir([path,file])
                        wells = fieldnames(exportdb);
                        for k = 1:length(wells)
                            if strcmp(ext,'.csv')
                                writetable(exportdb.(wells{k}).logs,[path,file,'\',wells{k},ext],'WriteMode','overwrite')
                            else
                                writetable(exportdb.(wells{k}).logs,[path,file,'\',wells{k},ext],'Delimiter','tab','WriteMode','overwrite')
                            end
                        end 
                end
%             catch
%                 disp('Save Error!')
%             end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Layout = uifigure('Visible', 'off');
            app.Layout.Name = 'LasSee';
            app.Layout.Icon = 'LasSee.png';
            app.AppGrid = uigridlayout(app.Layout);
            app.FileGrid = uigridlayout(app.AppGrid);
            app.FileBtnGrid = uigridlayout(app.FileGrid);
            app.AddBtn = uibutton(app.FileBtnGrid, 'push');
            app.AddBtn.ButtonPushedFcn = createCallbackFcn(app, @AddBtnPushed, true);
            app.AddBtn.Icon = 'Plus_208px.png';
            app.AddBtn.Text = 'Add';
            app.RemoveButton = uibutton(app.FileBtnGrid, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Icon = 'minus_208px.png';
            app.RemoveButton.Text = 'Remove';
            app.ClearButton = uibutton(app.FileBtnGrid, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'delete_file_208px.png';
            app.ClearButton.Text = 'Clear';
            app.FilesTbl = uitable(app.FileGrid);
            app.FilesTbl.ColumnName = {'LAS file'; 'LAS Well'; 'MATLAB Well'; 'Start'; 'Stop'; 'KB'; 'Logs'};
            app.FilesTbl.RowName = {};
            app.FilesTbl.SelectionType = 'row';
            app.FilesTbl.ColumnEditable = [false false true true true false false];
            app.FilesTbl.CellEditCallback = createCallbackFcn(app, @FilesTblCellEdit, true);
            app.FilesTbl.SelectionChangedFcn = createCallbackFcn(app, @FilesTblSelectionChanged, true);
            app.FilesTbl.Multiselect = 'off';
            app.TabGroup = uitabgroup(app.AppGrid);
            app.LogsTab = uitab(app.TabGroup);
            app.LogsTab.Title = 'Logs';
            app.LogsTbl = uitable(app.LogsTab);
            app.LogsTbl.ColumnName = {'Select'; 'Name'; 'Description'; 'Mean'; 'Min'; 'Max'};
            app.LogsTbl.RowName = {};
            app.LogsTbl.ColumnEditable = [true false false false false false];
            app.LogsTbl.CellEditCallback = createCallbackFcn(app, @LogsTblCellEdit, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataTabGrid = uigridlayout(app.DataTab);
            app.UIAxes = uiaxes(app.DataTabGrid);
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.DataTbl = uitable(app.DataTabGrid);
            app.DataTbl.ColumnName = '';
            app.DataTbl.RowName = {};
            app.DataTbl.SelectionType = 'column';
            app.DataTbl.SelectionChangedFcn = createCallbackFcn(app, @DataTblSelectionChanged, true);
            app.DataTbl.Multiselect = 'off';
            app.HeaderTab = uitab(app.TabGroup);
            app.HeaderTab.Title = 'Header';
            app.HeaderTxt = uitextarea(app.HeaderTab);
            app.HeaderTxt.Editable = 'off';
            app.BtnGrid = uigridlayout(app.AppGrid);
            app.CalculateStatisticsButton = uibutton(app.BtnGrid, 'push');
            app.CalculateStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateStatisticsButtonPushed, true);
            app.CalculateStatisticsButton.Icon = 'normal_distribution_histogram_208px.png';
            app.CalculateStatisticsButton.Text = 'Calculate Statistics';
            app.AddtoWSBtn = uibutton(app.BtnGrid, 'push');
            app.AddtoWSBtn.ButtonPushedFcn = createCallbackFcn(app, @AddtoWSBtnButtonPushed, true);
            app.AddtoWSBtn.Icon = 'MATLAB_400px.png';
            app.AddtoWSBtn.Text = 'Add to Workspace';
            app.CancelBtn = uibutton(app.BtnGrid, 'push');
            app.CancelBtn.ButtonPushedFcn = createCallbackFcn(app, @CancelBtnPushed, true);
            app.CancelBtn.Icon = 'close_208px.png';
            app.CancelBtn.Text = 'Cancel';
            app.ExportButton = uibutton(app.BtnGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Icon = 'microsoft_excel_208px.png';
            app.ExportButton.Text = {'Export'; ''};
            % Show the figure after all components are created
            app.Layout.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LasSee

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Layout)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Layout)
        end
    end
end